classdef P1___7Equipo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GuardarImagenButton           matlab.ui.control.Button
        PROCESADORDEIMAGENESLabel     matlab.ui.control.Label
        CargarImagenButton            matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OperacionesBsicasTab          matlab.ui.container.Tab
        DeshacerButton                matlab.ui.control.Button
        LabelInfoProcesada            matlab.ui.control.Label
        LabelInfoOriginal             matlab.ui.control.Label
        AnlisisdepxelesPanel          matlab.ui.container.Panel
        CalcularDistanciasButton      matlab.ui.control.Button
        OperacionesdeintensidadPanel  matlab.ui.container.Panel
        SustraccinButton              matlab.ui.control.Button
        AdicinButton                  matlab.ui.control.Button
        InversinBinariaButton         matlab.ui.control.Button
        InversinFotogrficaButton      matlab.ui.control.Button
        TransformacionesgeomtricasPanel  matlab.ui.container.Panel
        MosaicoButton                 matlab.ui.control.Button
        EspejoButton                  matlab.ui.control.Button
        RotarImagenButton             matlab.ui.control.Button
        DesplazarImagenButton         matlab.ui.control.Button
        ModificarTamaoButton          matlab.ui.control.Button
        UIAxesProcesada               matlab.ui.control.UIAxes
        UIAxesOriginal                matlab.ui.control.UIAxes
        EcualizacinTab                matlab.ui.container.Tab
        LabelInfoProcesada_2          matlab.ui.control.Label
        LabelInfoOriginal_2           matlab.ui.control.Label
        DeshacerButton_2              matlab.ui.control.Button
        EcualizacinPanel              matlab.ui.container.Panel
        EcualizarButton               matlab.ui.control.Button
        ShrinkButton                  matlab.ui.control.Button
        StrecthButton                 matlab.ui.control.Button
        HistogramaButton              matlab.ui.control.Button
        UIAxesHistogramaProcesada_2   matlab.ui.control.UIAxes
        UIAxesHistograma              matlab.ui.control.UIAxes
        UIAxesProcesada_2             matlab.ui.control.UIAxes
        UIAxesOriginal_2              matlab.ui.control.UIAxes
        UmbralizacinTab               matlab.ui.container.Tab
        LabelInfoProcesada_3          matlab.ui.control.Label
        LabelInfoOriginal_3           matlab.ui.control.Label
        DeshacerButton_3              matlab.ui.control.Button
        UmbralizacinPanel             matlab.ui.container.Panel
        UmbraladaptativoButton        matlab.ui.control.Button
        UmbralFijoButton              matlab.ui.control.Button
        UIAxesHistogramaProcesada_3   matlab.ui.control.UIAxes
        UIAxesHistograma_3            matlab.ui.control.UIAxes
        UIAxesProcesada_3             matlab.ui.control.UIAxes
        UIAxesOriginal_3              matlab.ui.control.UIAxes
        FiltrosTab                    matlab.ui.container.Tab
        DetecciondebordesPanel        matlab.ui.container.Panel
        PrewittButton                 matlab.ui.control.Button
        SobelXYButton                 matlab.ui.control.Button
        SobelYButton                  matlab.ui.control.Button
        SobelXButton                  matlab.ui.control.Button
        LabelInfoProcesada_4          matlab.ui.control.Label
        LabelInfoOriginal_4           matlab.ui.control.Label
        DeshacerButton_4              matlab.ui.control.Button
        ResaltarcaractersticasPanel   matlab.ui.container.Panel
        Laplaciano4vecinosButton      matlab.ui.control.Button
        Laplaciano8vecinosButton      matlab.ui.control.Button
        AgregarRuidoPanel             matlab.ui.container.Panel
        RuidoPoissonButton            matlab.ui.control.Button
        SalyPimientaButton            matlab.ui.control.Button
        RuidoGaussianoButton          matlab.ui.control.Button
        ReducirRuidoPanel             matlab.ui.container.Panel
        OrdennButton                  matlab.ui.control.Button
        GausianoButton                matlab.ui.control.Button
        ModaButton                    matlab.ui.control.Button
        MediaButton                   matlab.ui.control.Button
        MedianaButton                 matlab.ui.control.Button
        UIAxesOriginal_4              matlab.ui.control.UIAxes
        UIAxesRuido                   matlab.ui.control.UIAxes
        UIAxesProcesada_4             matlab.ui.control.UIAxes
        MorfolgicasTab                matlab.ui.container.Tab
        elementoestructurantePanel    matlab.ui.container.Panel
        Instruccionessecargaralamatrixycambielosvalorespor0sy1sLabel  matlab.ui.control.Label
        Clausura                      matlab.ui.control.Button
        Apertura                      matlab.ui.control.Button
        Erosion                       matlab.ui.control.Button
        Dilatacion                    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        LabelInfoProcesada_5          matlab.ui.control.Label
        LabelInfoOriginal_5           matlab.ui.control.Label
        DeshacerButton_5              matlab.ui.control.Button
        BinarizarlaImagenPanel        matlab.ui.container.Panel
        UmbralDadoButton              matlab.ui.control.Button
        metodoadaptativoButton        matlab.ui.control.Button
        UIAxesElementoEstructurante   matlab.ui.control.UIAxes
        UIAxesOriginal_5              matlab.ui.control.UIAxes
        UIAxesProcesada_5             matlab.ui.control.UIAxes
        ColordeOjosTab                matlab.ui.container.Tab
        DeshacerButton_6              matlab.ui.control.Button
        CambiarcolodeojosPanel        matlab.ui.container.Panel
        CambiarOjos                   matlab.ui.control.Button
        LabelInfoProcesada_6          matlab.ui.control.Label
        LabelInfoOriginal_6           matlab.ui.control.Label
        UIAxesOriginal_6              matlab.ui.control.UIAxes
        UIAxesProcesada_6             matlab.ui.control.UIAxes
    end

    % ------------------------------------------------------------
    % PROCESADOR DE IMÁGENES
    % Integrantes del equipo:
    % - Hugo Aldair Franco Romero
    % - Ignacio Sebastián Martínez Galicia
    % - Jesús López González
    % - Ramiro Vega Meza
    % ------------------------------------------------------------
    properties (Access = private)
    ImagenOriginal
    ImagenProcesada
    
    % <-Historil de cada ventana
    HistorialProcesamiento
    HistorialProcesamiento2
    HistorialProcesamiento3
    HistorialProcesamiento4
    HistorialProcesamiento5
    HistorialProcesamiento6

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % Inicializar la tabla con matriz 9x9
            matrizInicial = zeros(9,9);
            matrizInicial(5,5) = 1; % centro
            app.UITable.Data = matrizInicial;
        
            % Hacer todas las columnas editables
            app.UITable.ColumnEditable = true(1,9);

        end

        % Button pushed function: ModificarTamaoButton
        function ModificarTamanioButtonPushed(app, event)


    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar nuevo tamaño
    prompt = {'Nuevo ancho:', 'Nuevo alto:'};
    dlgtitle = 'Modificar tamaño';
    dims = [1 35];
    definput = {num2str(size(app.ImagenOriginal, 2)), num2str(size(app.ImagenOriginal, 1))};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    nuevoAncho = str2double(answer{1});
    nuevoAlto = str2double(answer{2});

    if isnan(nuevoAncho) || isnan(nuevoAlto) || nuevoAncho <= 0 || nuevoAlto <= 0
        uialert(app.UIFigure, 'Dimensiones inválidas.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end
    

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;


    % Redimensionar imagen
    imagenRedimensionada = imresize(imagenAntes, [nuevoAlto, nuevoAncho]);

    % Mostrar imagen procesada
    app.ImagenProcesada = imagenRedimensionada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar información
    infoProcesada = sprintf('Imagen redimensionada: %d x %d', nuevoAlto, nuevoAncho);
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona una imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);

    % Limpiar todos los ejes
    cla(app.UIAxesOriginal);
    cla(app.UIAxesOriginal_2);
    cla(app.UIAxesOriginal_3);
    cla(app.UIAxesOriginal_4);
    cla(app.UIAxesOriginal_5);
    cla(app.UIAxesOriginal_6);
    
    cla(app.UIAxesProcesada);
    cla(app.UIAxesProcesada_2);
    cla(app.UIAxesProcesada_3);
    cla(app.UIAxesProcesada_4);
    cla(app.UIAxesProcesada_5);
    cla(app.UIAxesProcesada_6);

    % Reiniciar variables
    app.ImagenOriginal = img;
    app.ImagenProcesada = [];
    app.HistorialProcesamiento = {};
    app.HistorialProcesamiento2 = {};
    app.HistorialProcesamiento3 = {};
    app.HistorialProcesamiento4 = {};
    app.HistorialProcesamiento5 = {};
    app.HistorialProcesamiento6 = {}; 

    % Mostrar imagen en todos los ejes originales
    imshow(img, 'Parent', app.UIAxesOriginal);
    imshow(img, 'Parent', app.UIAxesOriginal_2);
    imshow(img, 'Parent', app.UIAxesOriginal_3);
    imshow(img, 'Parent', app.UIAxesOriginal_4);
    imshow(img, 'Parent', app.UIAxesOriginal_5);
    imshow(img, 'Parent', app.UIAxesOriginal_6);

    % Obtener dimensiones
    alto = size(img, 1);
    ancho = size(img, 2);

    % Determinar tipo de imagen
    if ndims(img) == 3
        tipo = 'RGB';
    elseif islogical(img)
        tipo = 'Binaria';
    else
        tipo = 'Escala de grises';
    end

    % Obtener nombre y extensión del archivo
    [~, nombreArchivo, extension] = fileparts(file);

    % Crear texto informativo
    infoOriginal = sprintf(['Dimensiones: %d x %d píxeles\n' ...
                            'Tipo de imagen: %s\n' ...
                            'Nombre del archivo: %s\n' ...
                            'Formato: %s'], ...
                            alto, ancho, tipo, nombreArchivo, extension);

    % Actualizar etiquetas en todos los tabs
    app.LabelInfoOriginal.Text = infoOriginal;
    app.LabelInfoOriginal_2.Text = infoOriginal;
    app.LabelInfoOriginal_3.Text = infoOriginal;
    app.LabelInfoOriginal_4.Text = infoOriginal;
    app.LabelInfoOriginal_5.Text = infoOriginal;
    app.LabelInfoOriginal_6.Text = infoOriginal;


    % Reset etiqueta de imagen procesada
    app.LabelInfoProcesada.Text = 'Imagen procesada: -';
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)

    if ~isempty(app.HistorialProcesamiento)
        % Recuperar el último estado
        app.ImagenProcesada = app.HistorialProcesamiento{end};
        app.HistorialProcesamiento(end) = []; % Eliminar el último estado

        % Mostrar la imagen restaurada
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
        app.LabelInfoProcesada.Text = 'Se ha deshecho la última acción.';
    else
        uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
    end
        end

        % Button pushed function: InversinFotogrficaButton
        function InvertirFotograficaButtonPushed(app, event)
    % Verificar si hay una imagen cargada
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end
    
    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end
    
    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;
    
    % Invertir la imagen (funciona para RGB y escala de grises)
    app.ImagenProcesada = 255 - imagenAntes;
    
    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
    
    % Mostrar características de la imagen procesada
    infoProcesada = sprintf('Imagen invertida: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: InversinBinariaButton
        function vertirBinariaButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Convertir a escala de grises si es RGB
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Convertir a binaria usando un umbral (por ejemplo, 128)
    imagenBinaria = imbinarize(imagenGris, 128/255);

    % Invertir la imagen binaria
    imagenInvertida = ~imagenBinaria;

    % Convertir a formato uint8 (0 y 255)
    app.ImagenProcesada = uint8(imagenInvertida) * 255;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen binaria invertida: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: AdicinButton
        function AdicionImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen principal.', 'Error');
        return;
    end

    % Seleccionar segunda imagen
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona la segunda imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img2 = imread(imgPath);

    % Verificar que ambas imágenes tengan el mismo tamaño
    if ~isequal(size(app.ImagenOriginal), size(img2))
        uialert(app.UIFigure, 'Las imágenes deben tener el mismo tamaño y tipo.', 'Error');
        return;
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Realizar la suma y saturar valores mayores a 255
    suma = double(imagenAntes) + double(img2);
    suma(suma > 255) = 255;
    app.ImagenProcesada = uint8(suma);

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen sumada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: GuardarImagenButton
        function GuardarImagenButtonPushed(app, event)

    if isempty(app.ImagenProcesada)
        uialert(app.UIFigure, 'No hay imagen procesada para guardar.', 'Aviso');
        return;
    end

    [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'; '*.tif'}, 'Guardar imagen como');
    if isequal(file, 0)
        return; % El usuario canceló
    end

    imwrite(app.ImagenProcesada, fullfile(path, file));
    uialert(app.UIFigure, 'Imagen guardada exitosamente.', 'Éxito');
        end

        % Button pushed function: DesplazarImagenButton
        function DesplazarImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar desplazamientos en cada dirección
    prompt = {'Pixeles hacia arriba:', 'Pixeles hacia abajo:', ...
              'Pixeles hacia la izquierda:', 'Pixeles hacia la derecha:'};
    dlgtitle = 'Desplazamiento de imagen';
    dims = [1 35];
    definput = {'0', '0', '0', '0'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    arriba = str2double(answer{1});
    abajo = str2double(answer{2});
    izquierda = str2double(answer{3});
    derecha = str2double(answer{4});

    if any(isnan([arriba, abajo, izquierda, derecha]))
        uialert(app.UIFigure, 'Todos los valores deben ser numéricos.', 'Error');
        return;
    end

    % Calcular desplazamiento total en filas y columnas
    desplazamientoFilas = abajo - arriba;
    desplazamientoColumnas = derecha - izquierda;

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Aplicar desplazamiento
    imagenDesplazada = circshift(imagenAntes, [desplazamientoFilas, desplazamientoColumnas]);
    app.ImagenProcesada = imagenDesplazada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen desplazada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: CalcularDistanciasButton
        function CalcularDistanciasButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar coordenadas de dos píxeles
    prompt = {'Fila píxel p1:', 'Columna píxel p2:', 'Fila píxel q2:', 'Columna píxel q2:'};
    dlgtitle = 'Coordenadas de píxeles';
    dims = [1 35];
    definput = {'1', '1', '10', '10'};
    coords = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(coords)
        return;
    end

    % Convertir a números
    pFila = str2double(coords{1});
    pCol = str2double(coords{2});
    qFila = str2double(coords{3});
    qCol = str2double(coords{4});

    % Validar que estén dentro de la imagen
    [alto, ancho, ~] = size(app.ImagenOriginal);
    if any([pFila, pCol, qFila, qCol] < 1) || ...
       pFila > alto || qFila > alto || ...
       pCol > ancho || qCol > ancho
        uialert(app.UIFigure, 'Las coordenadas deben estar dentro de la imagen.', 'Error');
        return;
    end

    % Calcular distancias
    distEuclidiana = sqrt((pFila - qFila)^2 + (pCol - qCol)^2);
    distD4 = abs(pFila - qFila) + abs(pCol - qCol);
    distD8 = max(abs(pFila - qFila), abs(pCol - qCol));

    % Mostrar resultados
    msg = sprintf(['📏 Distancias entre píxeles:\n\n' ...
                   '🔹 Euclidiana: %.2f píxeles\n' ...
                   '🔹 D4 (Manhattan): %d píxeles\n' ...
                   '🔹 D8 (Chebyshev): %d píxeles'], ...
                   distEuclidiana, distD4, distD8);
    uialert(app.UIFigure, msg, 'Resultados de Distancia');

    % Mostrar puntos y línea en la imagen original
    hold(app.UIAxesOriginal, 'on');
    plot(app.UIAxesOriginal, pCol, pFila, 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Punto p en rojo
    plot(app.UIAxesOriginal, qCol, qFila, 'go', 'MarkerSize', 8, 'LineWidth', 2); % Punto q en verde
    line(app.UIAxesOriginal, [pCol qCol], [pFila qFila], 'Color', 'b', 'LineStyle', '--', 'LineWidth', 1.5); % Línea azul
    hold(app.UIAxesOriginal, 'off');
        end

        % Button pushed function: RotarImagenButton
        function RotarImagenButtonPushed(app, event)
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Lista de ángulos disponibles
    opciones = {'45', '90', '135', '180', '225', '270', '315', '360'};
    [seleccion, ok] = listdlg('PromptString', 'Selecciona el ángulo de rotación:', ...
                              'SelectionMode', 'single', ...
                              'ListString', opciones, ...
                              'Name', 'Rotar imagen');

    if ~ok
        return;
    end

    angulo = str2double(opciones{seleccion});

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Rotar imagen
    imagenRotada = imrotate(imagenAntes, angulo, 'bilinear', 'crop');

    % Recortar imagen rotada al tamaño original
    [alto, ancho] = size(imagenAntes);
    [altoRotado, anchoRotado, ~] = size(imagenRotada);

    % Calcular centro de la imagen rotada
    centroY = floor(altoRotado / 2);
    centroX = floor(anchoRotado / 2);

    % Calcular límites de recorte
    inicioY = max(1, centroY - floor(alto / 2));
    finY = inicioY + alto - 1;
    inicioX = max(1, centroX - floor(ancho / 2));
    finX = inicioX + ancho - 1;

    % Asegurar que no se excedan los límites
    finY = min(finY, altoRotado);
    finX = min(finX, anchoRotado);

    % Recortar imagen rotada
    imagenRotada = imagenRotada(inicioY:finY, inicioX:finX, :);

    % Asignar imagen procesada
    app.ImagenProcesada = imagenRotada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen rotada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: EspejoButton
        function EspejoImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Opciones de espejo
    opciones = {'Horizontal', 'Vertical', 'Ambos'};
    [seleccion, ok] = listdlg('PromptString', 'Selecciona tipo de espejo:', ...
                              'SelectionMode', 'single', ...
                              'ListString', opciones);

    if ~ok
        return; % Usuario canceló
    end

    % Usar imagen procesada si existe, si no, usar la original
    if isempty(app.ImagenProcesada)
        imagenAntes = app.ImagenOriginal;
    else
        imagenAntes = app.ImagenProcesada;
    end

    % Guardar el estado actual en el historial antes de modificar
    if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
        app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = imagenAntes;

    % Aplicar operación de espejo
    switch opciones{seleccion}
        case 'Horizontal'
            imagenEspejo = fliplr(imagenAntes); % Espejo horizontal
        case 'Vertical'
            imagenEspejo = flipud(imagenAntes); % Espejo vertical
        case 'Ambos'
            imagenEspejo = flipud(fliplr(imagenAntes)); % Espejo horizontal y vertical
    end

    app.ImagenProcesada = imagenEspejo;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen con efecto espejo: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: MosaicoButton
        function MosaicoEspejoButtonPushed(app, event)
    
        if isempty(app.ImagenOriginal)
            uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
            return;
        end
    
        % Usar imagen procesada si existe, si no, usar la original
        if isempty(app.ImagenProcesada)
            imagenAntes = app.ImagenOriginal;
        else
            imagenAntes = app.ImagenProcesada;
        end
    
        % Guardar el estado actual en el historial antes de modificar
        if ~isfield(app, 'HistorialProcesamiento') || isempty(app.HistorialProcesamiento)
            app.HistorialProcesamiento = {};
        end
        app.HistorialProcesamiento{end+1} = imagenAntes;
    
        % Crear reflejos
        imgH = fliplr(imagenAntes);       % Espejo horizontal
        imgV = flipud(imagenAntes);       % Espejo vertical
        imgHV = flipud(imgH);             % Espejo horizontal + vertical
    
        % Crear mosaico 2x2
        mosaico = [imagenAntes, imgH; imgV, imgHV];
    
        % Redimensionar al tamaño original
        mosaicoFinal = imresize(mosaico, [size(imagenAntes,1), size(imagenAntes,2)]);
    
        % Mostrar imagen procesada
        app.ImagenProcesada = mosaicoFinal;
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
    
        % Mostrar características
        infoProcesada = sprintf('Imagen mosaico: %d x %d', ...
            size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
        app.LabelInfoProcesada.Text = infoProcesada;
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Convertir a escala de grises si es RGB
    if size(app.ImagenOriginal, 3) == 3
        imagenGris = rgb2gray(app.ImagenOriginal);
    else
        imagenGris = app.ImagenOriginal;
    end

    % Mostrar histograma en UIAxesHistograma
    cla(app.UIAxesHistograma); % Limpiar eje
    histogram(app.UIAxesHistograma, imagenGris(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');
        end

        % Button pushed function: StrecthButton
        function StretchButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Pedir intervalo al usuario
    prompt = {'Valor mínimo (0-255):', 'Valor máximo (0-255):'};
    dlgtitle = 'Intervalo para Stretch';
    dims = [1 35];
    definput = {'50', '200'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    minVal = str2double(answer{1});
    maxVal = str2double(answer{2});
    if isnan(minVal) || isnan(maxVal) || minVal < 0 || maxVal > 255 || minVal >= maxVal
        uialert(app.UIFigure, 'Intervalo inválido. Asegúrate de que 0 ≤ mínimo < máximo ≤ 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar Stretch
    imagenStretch = imadjust(imagenGris, [minVal/255, maxVal/255], [0, 1]);

    % Mostrar imagen modificada
    app.ImagenProcesada = imagenStretch;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen modificada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenStretch(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen Stretch');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen Stretch: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: ShrinkButton
        function ShrinkButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar imagen procesada si existe, si no, usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Pedir intervalo al usuario
    prompt = {'Nuevo mínimo (0-255):', 'Nuevo máximo (0-255):'};
    dlgtitle = 'Intervalo para Shrink';
    dims = [1 35];
    definput = {'50', '200'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    nuevoMin = str2double(answer{1});
    nuevoMax = str2double(answer{2});
    if isnan(nuevoMin) || isnan(nuevoMax) || nuevoMin < 0 || nuevoMax > 255 || nuevoMin >= nuevoMax
        uialert(app.UIFigure, 'Intervalo inválido. Asegúrate de que 0 ≤ mínimo < máximo ≤ 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar Shrink
    imagenShrink = imadjust(imagenGris, stretchlim(imagenGris), [nuevoMin/255, nuevoMax/255]);

    % Mostrar imagen modificada
    app.ImagenProcesada = imagenShrink;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen modificada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenShrink(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen Shrink');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen Shrink: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: EcualizarButton
        function EcualizarImagenButtonPushed(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma);
    histogram(app.UIAxesHistograma, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Guardar el estado actual en el historial del segundo tab
    if isempty(app.HistorialProcesamiento2)
        app.HistorialProcesamiento2 = {};
    end
    app.HistorialProcesamiento2{end+1} = imagenAntes;

    % Aplicar ecualización
    imagenEcualizada = histeq(imagenGris);

    % Mostrar imagen ecualizada
    app.ImagenProcesada = imagenEcualizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);

    % Mostrar histograma de imagen ecualizada
    cla(app.UIAxesHistogramaProcesada_2);
    histogram(app.UIAxesHistogramaProcesada_2, imagenEcualizada(:), 256);
    title(app.UIAxesHistogramaProcesada_2, 'Histograma de imagen ecualizada');
    xlabel(app.UIAxesHistogramaProcesada_2, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_2, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_2
    infoProcesada = sprintf('Imagen ecualizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_2.Text = infoProcesada;
        end

        % Button pushed function: UmbralFijoButton
        function UmbralFijoButtonPushe(app, event)
    % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_3);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma_3);
    histogram(app.UIAxesHistograma_3, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma_3, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma_3, 'Intensidad');
    ylabel(app.UIAxesHistograma_3, 'Frecuencia');

    % usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Solicitar valor de umbral
    prompt = {'Ingresa el valor de umbral (0-255):'};
    dlgtitle = 'Umbralización fija';
    dims = [1 35];
    definput = {'128'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    if isempty(answer)
        return;
    end
    umbral = str2double(answer{1});
    if isnan(umbral) || umbral < 0 || umbral > 255
        uialert(app.UIFigure, 'El valor debe estar entre 0 y 255.', 'Error');
        return;
    end

    % Guardar el estado actual en el historial del tercer tab
    if isempty(app.HistorialProcesamiento3)
        app.HistorialProcesamiento3 = {};
    end
    app.HistorialProcesamiento3{end+1} = imagenAntes;

    % Aplicar umbralización
    imagenBinaria = imbinarize(imagenGris, umbral / 255);
    imagenUmbralizada = uint8(imagenBinaria) * 255;

    % Mostrar imagen umbralizada
    app.ImagenProcesada = imagenUmbralizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);

    % Mostrar histograma de imagen umbralizada
    cla(app.UIAxesHistogramaProcesada_3);
    histogram(app.UIAxesHistogramaProcesada_3, imagenUmbralizada(:), 256);
    title(app.UIAxesHistogramaProcesada_3, 'Histograma de imagen umbralizada');
    xlabel(app.UIAxesHistogramaProcesada_3, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_3, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_3
    infoProcesada = sprintf('Imagen umbralizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_3.Text = infoProcesada;
        end

        % Button pushed function: UmbraladaptativoButton
        function UmbralAdaptativoButtonPushed(app, event)
     % Validar que haya imagen original
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Mostrar imagen original en color
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_3);

    % Mostrar histograma de la imagen original (en escala de grises)
    if size(app.ImagenOriginal, 3) == 3
        imagenGrisOriginal = rgb2gray(app.ImagenOriginal);
    else
        imagenGrisOriginal = app.ImagenOriginal;
    end
    cla(app.UIAxesHistograma_3);
    histogram(app.UIAxesHistograma_3, imagenGrisOriginal(:), 256);
    title(app.UIAxesHistograma_3, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma_3, 'Intensidad');
    ylabel(app.UIAxesHistograma_3, 'Frecuencia');

    % Usar la original
    imagenAntes = app.ImagenOriginal;

    % Convertir a escala de grises para procesar
    if size(imagenAntes, 3) == 3
        imagenGris = rgb2gray(imagenAntes);
    else
        imagenGris = imagenAntes;
    end

    % Guardar el estado actual en el historial del tercer tab
    if isempty(app.HistorialProcesamiento3)
        app.HistorialProcesamiento3 = {};
    end
    app.HistorialProcesamiento3{end+1} = imagenAntes;

    % Aplicar umbralización adaptativa
    umbralAdaptativo = adaptthresh(imagenGris, 0.5); % Puedes ajustar la sensibilidad
    imagenBinaria = imbinarize(imagenGris, umbralAdaptativo);
    imagenUmbralizada = uint8(imagenBinaria) * 255;

    % Mostrar imagen umbralizada
    app.ImagenProcesada = imagenUmbralizada;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);

    % Mostrar histograma de imagen umbralizada
    cla(app.UIAxesHistogramaProcesada_3);
    histogram(app.UIAxesHistogramaProcesada_3, imagenUmbralizada(:), 256);
    title(app.UIAxesHistogramaProcesada_3, 'Histograma de imagen umbralizada');
    xlabel(app.UIAxesHistogramaProcesada_3, 'Intensidad');
    ylabel(app.UIAxesHistogramaProcesada_3, 'Frecuencia');

    % Mostrar información en LabelInfoProcesada_3
    infoProcesada = sprintf('Imagen umbralizada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada_3.Text = infoProcesada;
        end

        % Button pushed function: SustraccinButton
        function SustraccionImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen principal.', 'Error');
        return;
    end

    % Seleccionar segunda imagen
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona la segunda imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img2 = imread(imgPath);

    % Verificar que ambas imágenes tengan el mismo tamaño
    if ~isequal(size(app.ImagenOriginal), size(img2))
        uialert(app.UIFigure, 'Las imágenes deben tener el mismo tamaño y tipo.', 'Error');
        return;
    end

    % Realizar la sustracción usando diferencia absoluta
    resta = abs(double(app.ImagenOriginal) - double(img2));

    % Normalizar para mejorar contraste visual
    resta = uint8(255 * mat2gray(resta));
    app.ImagenProcesada = resta;

    %Hisotiral de procesamiento
    if isempty(app.HistorialProcesamiento)
    app.HistorialProcesamiento = {};
    end
    app.HistorialProcesamiento{end+1} = app.ImagenOriginal;


    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

        end

        % Button pushed function: DeshacerButton_2
        function DeshacerButtonPushed_2(app, event)

     if ~isempty(app.HistorialProcesamiento2)
            % Recuperar el último estado
            app.ImagenProcesada = app.HistorialProcesamiento2{end};
            app.HistorialProcesamiento2(end) = [];
    
            % Mostrar la imagen restaurada en el segundo tab
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);
    
            % Limpiar el histograma procesado
            cla(app.UIAxesHistogramaProcesada_2);
            title(app.UIAxesHistogramaProcesada_2, '');
            xlabel(app.UIAxesHistogramaProcesada_2, '');
            ylabel(app.UIAxesHistogramaProcesada_2, '');
    
            % Mensaje informativo
            app.LabelInfoProcesada_2.Text = 'Se ha deshecho la última acción.';
        else
            uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
     end
        end

        % Button pushed function: DeshacerButton_3
        function DeshacerButtonPushed_3(app, event)
            
     if ~isempty(app.HistorialProcesamiento3)
            % Recuperar el último estado
            app.ImagenProcesada = app.HistorialProcesamiento3{end};
            app.HistorialProcesamiento3(end) = [];
    
            % Mostrar la imagen restaurada en el 3 tab
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);
    
            % Limpiar el histograma procesado
            cla(app.UIAxesHistogramaProcesada_3);
            title(app.UIAxesHistogramaProcesada_3, '');
            xlabel(app.UIAxesHistogramaProcesada_3, '');
            ylabel(app.UIAxesHistogramaProcesada_3, '');
    
            % Mensaje informativo
            app.LabelInfoProcesada_3.Text = 'Se ha deshecho la última acción.';
        else
            uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
     end
        end

        % Button pushed function: RuidoGaussianoButton
        function RuidoGaussianoButtonPushed(app, event)
                    % Verifica si hay una imagen cargada
                if isempty(app.UIAxesOriginal_4.Children)
                    uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                    return;
                end
            
                % Solicitar parámetros al usuario
                prompt = {'Media (ej. μ > 0 o μ < 0):', 'Varianza (ej. 0.01):'};
                dlgtitle = 'Parámetros de Ruido Gaussiano';
                dims = [1 35];
                definput = {'0', '0.01'};
                answer = inputdlg(prompt, dlgtitle, dims, definput);
            
                if isempty(answer)
                    return; % Usuario canceló
                end
            
                media = str2double(answer{1});
                varianza = str2double(answer{2});
            
                if isnan(media) || isnan(varianza) || varianza < 0
                    uialert(app.UIFigure, 'Valores inválidos. Asegúrate de ingresar números válidos.', 'Error');
                    return;
                end
            
                % Obtener imagen actual (procesada si existe, si no, original)
                if isempty(app.ImagenProcesada)
                    imagenAntes = app.ImagenOriginal;
                else
                    imagenAntes = app.ImagenProcesada;
                end
            
                % Guardar en historial
                if isempty(app.HistorialProcesamiento4)
                    app.HistorialProcesamiento4 = {};
                end
                app.HistorialProcesamiento4{end+1} = imagenAntes;
            
                % Convertir a escala de grises si es RGB
                if size(imagenAntes, 3) == 3
                    imagenAntes = rgb2gray(imagenAntes);
                end
            
                % Aplicar ruido gaussiano
                imgRuido = imnoise(imagenAntes, 'gaussian', media, varianza);
            
                % Actualizar imagen procesada
                app.ImagenProcesada = imgRuido;
            
                % Mostrar imagen con ruido
                imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_4);
                title(app.UIAxesOriginal_4, 'Imagen Original');
            
                imshow(imgRuido, 'Parent', app.UIAxesRuido);
                title(app.UIAxesRuido, sprintf('Ruido Gaussiano (μ=%.2f, σ²=%.2f)', media, varianza));
        end

        % Button pushed function: SalyPimientaButton
        function SalyPimientaButtonPushed(app, event)
            % Verifica si hay una imagen cargada
                if isempty(app.UIAxesOriginal_4.Children)
                    uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                    return;
                end
            
                % Solicitar densidad del ruido
                prompt = {'Densidad (ej. 0.02):'};
                dlgtitle = 'Parámetro de Ruido Sal y Pimienta';
                dims = [1 35];
                definput = {'0.02'};
                answer = inputdlg(prompt, dlgtitle, dims, definput);
            
                if isempty(answer)
                    return; % Usuario canceló
                end
            
                densidad = str2double(answer{1});
            
                if isnan(densidad) || densidad <= 0 || densidad > 1
                    uialert(app.UIFigure, 'Valor inválido. Debe estar entre 0 y 1.', 'Error');
                    return;
                end
            
                % Obtener imagen actual (procesada si existe, si no, original)
                if isempty(app.ImagenProcesada)
                    imagenAntes = app.ImagenOriginal;
                else
                    imagenAntes = app.ImagenProcesada;
                end
            
                % Guardar en historial
                if isempty(app.HistorialProcesamiento4)
                    app.HistorialProcesamiento4 = {};
                end
                app.HistorialProcesamiento4{end+1} = imagenAntes;
            
                % Convertir a escala de grises si es RGB
                if size(imagenAntes, 3) == 3
                    imagenAntes = rgb2gray(imagenAntes);
                end
            
                % Aplicar ruido sal y pimienta
                imgRuido = imnoise(imagenAntes, 'salt & pepper', densidad);
            
                % Actualizar imagen procesada
                app.ImagenProcesada = imgRuido;
            
                % Mostrar imagen original y con ruido
                imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_4);
                title(app.UIAxesOriginal_4, 'Imagen Original');
            
                imshow(imgRuido, 'Parent', app.UIAxesRuido);
                title(app.UIAxesRuido, sprintf('Ruido Sal y Pimienta (densidad=%.3f)', densidad));
        end

        % Button pushed function: RuidoPoissonButton
        function RuidoPoissonButtonPushed(app, event)
             % Verifica si hay una imagen cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Obtener imagen actual (procesada si existe, si no, original)
            if isempty(app.ImagenProcesada)
                imagenAntes = app.ImagenOriginal;
            else
                imagenAntes = app.ImagenProcesada;
            end
        
            % Guardar en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imagenAntes;
        
            % Convertir a escala de grises si es RGB
            if size(imagenAntes, 3) == 3
                imagenAntes = rgb2gray(imagenAntes);
            end
        
            % Aplicar ruido Poisson (no requiere parámetros)
            imgRuido = imnoise(imagenAntes, 'poisson');
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgRuido;
        
            % Mostrar imagen original y con ruido
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_4);
            title(app.UIAxesOriginal_4, 'Imagen Original');
        
            imshow(imgRuido, 'Parent', app.UIAxesRuido);
            title(app.UIAxesRuido, 'Ruido Poisson');
        end

        % Button pushed function: MedianaButton
        function MedianaButtonPushed(app, event)
            if isempty(app.UIAxesRuido.Children)
                uialert(app.UIFigure, 'No hay imagen con ruido para aplicar el filtro.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la imagen con ruido
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesRuido.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a gris si es RGB
            if size(imgEntrada, 3) == 3
                imgEntrada = rgb2gray(imgEntrada);
            end
        
            % Aplicar filtro mediana
            imgSuavizada = medfilt2(imgEntrada, [3 3]);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgSuavizada;
        
            % Mostrar imagen procesada
            imshow(imgSuavizada, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Filtro Mediana (3x3)');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Mediana): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: MediaButton
        function MediaButtonPushed(app, event)
            % Button pushed function: MediaButton
            if isempty(app.UIAxesRuido.Children)
                uialert(app.UIFigure, 'No hay imagen con ruido para aplicar el filtro.', 'Error');
                return;
            end
        
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesRuido.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            h = fspecial('average', [3 3]);
            imgSuavizada = imfilter(imgEntrada, h);
        
            app.ImagenProcesada = imgSuavizada;
            imshow(imgSuavizada, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Filtro Media (3x3)');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Media): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: ModaButton
        function ModaButtonPushed(app, event)
                if isempty(app.UIAxesRuido.Children)
                    uialert(app.UIFigure, 'No hay imagen con ruido para aplicar el filtro.', 'Error');
                    return;
                end
            
                if isempty(app.UIAxesProcesada_4.Children)
                    imgEntrada = app.UIAxesRuido.Children.CData;
                else
                    imgEntrada = app.UIAxesProcesada_4.Children.CData;
                end
            
                if isempty(app.HistorialProcesamiento4)
                    app.HistorialProcesamiento4 = {};
                end
                app.HistorialProcesamiento4{end+1} = imgEntrada;
            
                imgGris = im2gray(imgEntrada);
                [filas, cols] = size(imgGris);
                imgSuavizada = imgGris;
            
                for i = 2:filas-1
                    for j = 2:cols-1
                        ventana = imgGris(i-1:i+1, j-1:j+1);
                        imgSuavizada(i,j) = mode(ventana(:));
                    end
                end
            
                app.ImagenProcesada = uint8(imgSuavizada);
                imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_4);
                title(app.UIAxesProcesada_4, 'Filtro Moda (3x3)');
            
                % Mostrar características de la imagen procesada
                infoProcesada = sprintf('Imagen filtrada (Moda): %d x %d', ...
                    size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
                app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: GausianoButton
        function GausianoButtonPushed(app, event)
            if isempty(app.UIAxesRuido.Children)
                uialert(app.UIFigure, 'No hay imagen con ruido para aplicar el filtro.', 'Error');
                return;
            end
        
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesRuido.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            prompt = {'Sigma (ej. 0.5 - 2.0):'};
            answer = inputdlg(prompt, 'Filtro Gaussiano', [1 35], {'1'});
            if isempty(answer), return; end
            sigma = str2double(answer{1});
        
            imgSuavizada = imgaussfilt(imgEntrada, sigma);
        
            app.ImagenProcesada = imgSuavizada;
            imshow(imgSuavizada, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, sprintf('Filtro Gaussiano (σ=%.2f)', sigma));
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Gaussiano): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: OrdennButton
        function OrdennButtonPushed(app, event)
           % Verificar que hay imagen con ruido
            if isempty(app.UIAxesRuido.Children)
                uialert(app.UIFigure, 'No hay imagen con ruido para aplicar el filtro.', 'Error');
                return;
            end
        
            % Obtener imagen actual
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesRuido.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Solicitar valor de n
            prompt = {'Ingresa el valor de n (1 a 9):'};
            dlgtitle = 'Filtro Orden n';
            dims = [1 35];
            definput = {'5'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if isempty(answer), return; end
            n = str2double(answer{1});
            if isnan(n) || n < 1 || n > 9
                uialert(app.UIFigure, 'Valor inválido. Debe estar entre 1 y 9.', 'Error');
                return;
            end
        
            % Convertir a gris si es RGB
            if size(imgEntrada, 3) == 3
                imgEntrada = rgb2gray(imgEntrada);
            end
        
            % Aplicar filtro de orden n
            imgFiltrada = ordfilt2(imgEntrada, n, true(3));
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgFiltrada;
            imshow(imgFiltrada, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, sprintf('Filtro Orden n (n=%d)', n));
        
            % Actualizar etiqueta
            infoProcesada = sprintf('Imagen filtrada (Orden n=%d): %d x %d', ...
                n, size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: Laplaciano8vecinosButton
        function Laplaciano8vecinosButtonPushed(app, event)
                     % Verifica si hay imagen original cargada
                    if isempty(app.UIAxesOriginal_4.Children)
                        uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                        return;
                    end
                
                    % Usar imagen procesada si existe, si no, usar la original
                    if isempty(app.UIAxesProcesada_4.Children)
                        imgEntrada = app.UIAxesOriginal_4.Children.CData;
                    else
                        imgEntrada = app.UIAxesProcesada_4.Children.CData;
                    end
                
                    % Guardar estado previo en historial
                    if isempty(app.HistorialProcesamiento4)
                        app.HistorialProcesamiento4 = {};
                    end
                    app.HistorialProcesamiento4{end+1} = imgEntrada;
                
                    % Convertir a escala de grises si es RGB
                    imgGris = im2gray(imgEntrada);
                
                    % Máscara Laplaciana para 8 vecinos
                    h = [-1 -1 -1; -1 8 -1; -1 -1 -1];
                
                    % Aplicar filtro Laplaciano
                    imgFiltro = imfilter(imgGris, h);
                
                    % Normalizar para visualización
                    imgResultado = mat2gray(imgFiltro);
                
                    % Actualizar imagen procesada
                    app.ImagenProcesada = imgResultado;
                
                    % Mostrar resultado en UIAxesProcesada_4
                    imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
                    title(app.UIAxesProcesada_4, 'Laplaciano 8 Vecinos (Imagen Original)');

        end

        % Button pushed function: Laplaciano4vecinosButton
        function Laplaciano4vecinosButtonPushed(app, event)
            % Verifica si hay imagen original cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la original
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesOriginal_4.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a escala de grises si es RGB
            imgGris = im2gray(imgEntrada);
        
            % Máscara Laplaciana para 4 vecinos
            h = [0 -1 0; -1 4 -1; 0 -1 0];
        
            % Aplicar filtro Laplaciano
            imgFiltro = imfilter(imgGris, h);
        
            % Normalizar para visualización
            imgResultado = mat2gray(imgFiltro);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgResultado;
        
            % Mostrar resultado en UIAxesProcesada_4
            imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Laplaciano 4 Vecinos (Imagen Original)');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Laplaciano 4): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: SobelXButton
        function SobelXButtonPushed(app, event)
            % Verifica si hay imagen original cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la original
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesOriginal_4.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a escala de grises si es RGB
            imgGris = im2gray(imgEntrada);
        
            % Máscara Sobel para X
            hSobelX = [-1 0 1; -2 0 2; -1 0 1];
        
            % Aplicar filtro Sobel X
            imgFiltro = imfilter(imgGris, hSobelX);
        
            % Normalizar para visualización
            imgResultado = mat2gray(imgFiltro);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgResultado;
        
            % Mostrar resultado en UIAxesProcesada_4
            imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Detección de Bordes - Sobel X');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Sobel X): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: SobelYButton
        function SobelYButtonPushed(app, event)
        
            % Verifica si hay imagen original cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la original
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesOriginal_4.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a escala de grises si es RGB
            imgGris = im2gray(imgEntrada);
        
            % Máscara Sobel para Y
            hSobelY = [-1 -2 -1; 0 0 0; 1 2 1];
        
            % Aplicar filtro Sobel Y
            imgFiltro = imfilter(imgGris, hSobelY);
        
            % Normalizar para visualización
            imgResultado = mat2gray(imgFiltro);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgResultado;
        
            % Mostrar resultado en UIAxesProcesada_4
            imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Detección de Bordes - Sobel Y');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Sobel Y): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            % Verifica si hay imagen original cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la original
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesOriginal_4.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a escala de grises si es RGB
            imgGris = im2gray(imgEntrada);
        
            % Máscaras Prewitt para X y Y
            hPrewittX = [-1 0 1; -1 0 1; -1 0 1];
            hPrewittY = [-1 -1 -1; 0 0 0; 1 1 1];
        
            % Aplicar filtros Prewitt X y Y
            imgX = imfilter(imgGris, hPrewittX);
            imgY = imfilter(imgGris, hPrewittY);
        
            % Combinar magnitudes
            imgXY = sqrt(double(imgX).^2 + double(imgY).^2);
        
            % Normalizar para visualización
            imgResultado = mat2gray(imgXY);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgResultado;
        
            % Mostrar resultado en UIAxesProcesada_4
            imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Detección de Bordes - Prewitt X+Y');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Prewitt X+Y): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: SobelXYButton
        function SobelXYButtonPushed(app, event)
            % Verifica si hay imagen original cargada
            if isempty(app.UIAxesOriginal_4.Children)
                uialert(app.UIFigure, 'No hay imagen original cargada.', 'Error');
                return;
            end
        
            % Usar imagen procesada si existe, si no, usar la original
            if isempty(app.UIAxesProcesada_4.Children)
                imgEntrada = app.UIAxesOriginal_4.Children.CData;
            else
                imgEntrada = app.UIAxesProcesada_4.Children.CData;
            end
        
            % Guardar estado previo en historial
            if isempty(app.HistorialProcesamiento4)
                app.HistorialProcesamiento4 = {};
            end
            app.HistorialProcesamiento4{end+1} = imgEntrada;
        
            % Convertir a escala de grises si es RGB
            imgGris = im2gray(imgEntrada);
        
            % Máscaras Sobel para X y Y
            hSobelX = [-1 0 1; -2 0 2; -1 0 1];
            hSobelY = [-1 -2 -1; 0 0 0; 1 2 1];
        
            % Aplicar filtros Sobel X y Y
            imgX = imfilter(double(imgGris), hSobelX);
            imgY = imfilter(double(imgGris), hSobelY);
        
            % Combinar magnitudes
            imgXY = sqrt(imgX.^2 + imgY.^2);
        
            % Normalizar para visualización
            imgResultado = mat2gray(imgXY);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = imgResultado;
        
            % Mostrar resultado en UIAxesProcesada_4
            imshow(imgResultado, 'Parent', app.UIAxesProcesada_4);
            title(app.UIAxesProcesada_4, 'Detección de Bordes - Sobel X+Y');
        
            % Mostrar características de la imagen procesada
            infoProcesada = sprintf('Imagen filtrada (Sobel X+Y): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_4.Text = infoProcesada;

        end

        % Button pushed function: DeshacerButton_4
        function DeshacerButtonPushed_4(app, event)
     if ~isempty(app.HistorialProcesamiento4)
            % Recuperar el último estado
            app.ImagenProcesada = app.HistorialProcesamiento4{end};
            app.HistorialProcesamiento4(end) = [];
    
            % Mostrar la imagen restaurada en el 4 tab
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_4);
    
            % Limpiar el UIAxesRuido
            cla(app.UIAxesRuido);
            title(app.UIAxesRuido, '');
            xlabel(app.UIAxesRuido, '');
            ylabel(app.UIAxesRuido, '');
    
            % Mensaje informativo
            app.LabelInfoProcesada_4.Text = 'Se ha deshecho la última acción.';
        else
            uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
     end
        end

        % Button pushed function: metodoadaptativoButton
        function metodoadaptativoButtonPushed(app, event)
            % Verificar si hay una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end
        
            % Convertir a escala de grises si es RGB
            if size(app.ImagenOriginal, 3) == 3
                imagenGris = rgb2gray(app.ImagenOriginal);
            else
                imagenGris = app.ImagenOriginal;
            end
        
            % Aplicar umbral adaptativo
            umbralAdaptativo = adaptthresh(imagenGris, 0.5); % Puedes ajustar la sensibilidad
            imagenBinaria = imbinarize(imagenGris, umbralAdaptativo);
        
            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;
        
            % Mostrar imagen original y binaria
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(imagenBinaria, 'Parent', app.UIAxesProcesada_5);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenBinaria) * 255;
        
            % Mostrar información
            infoProcesada = sprintf('Imagen binarizada (adaptativa): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;
        end

        % Button pushed function: DeshacerButton_5
        function DeshacerButtonPushed_5(app, event)
              if ~isempty(app.HistorialProcesamiento5)
                    % Recuperar el último estado
                    app.ImagenProcesada = app.HistorialProcesamiento5{end};
                    app.HistorialProcesamiento5(end) = [];
            
                    % Mostrar la imagen restaurada en el 4 tab
                    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_5);
            
            
                    % Mensaje informativo
                    app.LabelInfoProcesada_5.Text = 'Se ha deshecho la última acción.';
                else
                    uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
             end
        end

        % Button pushed function: UmbralDadoButton
        function UmbralDadoButtonPushed(app, event)
            % Verificar si hay una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end
        
            % Convertir a escala de grises si es RGB
            if size(app.ImagenOriginal, 3) == 3
                imagenGris = rgb2gray(app.ImagenOriginal);
            else
                imagenGris = app.ImagenOriginal;
            end
        
            % Solicitar valor de umbral al usuario
            prompt = {'Ingresa el valor de umbral (0-255):'};
            dlgtitle = 'Umbralización fija';
            dims = [1 35];
            definput = {'128'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
        
            if isempty(answer)
                return; % Usuario canceló
            end
        
            umbral = str2double(answer{1});
        
            % Validar el valor
            if isnan(umbral) || umbral < 0 || umbral > 255
                uialert(app.UIFigure, 'El valor debe estar entre 0 y 255.', 'Error');
                return;
            end
        
            % Aplicar binarización
            imagenBinaria = imbinarize(imagenGris, umbral / 255);
        
            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;
        
            % Mostrar imagen original y binaria
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(imagenBinaria, 'Parent', app.UIAxesProcesada_5);
        
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenBinaria) * 255;
        
            % Mostrar información
            infoProcesada = sprintf('Imagen binarizada (umbral fijo): %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;
        end

        % Button pushed function: Dilatacion
        function DilatacionButtonPushed(app, event)
            % Verificar si hay imagen binaria (procesada o original)
            if isempty(app.ImagenProcesada) || ~islogical(app.ImagenProcesada > 0)
                uialert(app.UIFigure, 'La imagen debe ser binaria antes de aplicar operaciones morfológicas.', 'Error');
                return;
            end
        
            % Obtener el elemento estructurante desde la UITable
            elementoUsuario = logical(app.UITable.Data); % convertir a lógico
            SE = strel('arbitrary', elementoUsuario);    % crear elemento estructurante

            
            % Mostrar el elemento estructurante en el UIAxes
            elementoUsuario = logical(app.UITable.Data);

            % Mostrar el ElementoEstructurante
            imshow(elementoUsuario, 'Parent', app.UIAxesElementoEstructurante);
            title(app.UIAxesElementoEstructurante, 'Elemento Estructurante');
            
            % Confirmación del usuario
            choice = questdlg('¿Desea aplicar la operación con este elemento?', ...
                'Confirmación', 'Sí', 'No', 'Sí');
            
            % Limpiar el UIAxes después de confirmar
            cla(app.UIAxesElementoEstructurante);
            
            % Cancelar si el usuario no confirma
            if strcmp(choice, 'No')
                return;
            end
        
            % Aplicar dilatación
            imagenDilatada = imdilate(app.ImagenProcesada > 0, SE); % convertir a binaria lógica

            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;
        
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenDilatada) * 255;
        
            % Mostrar imagen original y dilatada
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_5);
        
            % Mostrar información
            infoProcesada = sprintf('Imagen dilatada: %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;
        
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
           % Obtener la nueva celda editada
            newValue = event.NewData;
        
            % Validar que sea 0 o 1
            if ~(newValue == 0 || newValue == 1)
                % Restaurar el valor anterior (usar paréntesis)
                app.UITable.Data(event.Indices(1), event.Indices(2)) = event.PreviousData;
        
                % Mostrar alerta
                uialert(app.UIFigure, 'Solo se permiten valores 0 o 1.', 'Valor inválido');
            end

        end

        % Button pushed function: Erosion
        function ErosionButtonPushed(app, event)
            % Verificar si hay imagen binaria (procesada o original)
            if isempty(app.ImagenProcesada) || ~islogical(app.ImagenProcesada > 0)
                uialert(app.UIFigure, 'La imagen debe ser binaria antes de aplicar operaciones morfológicas.', 'Error');
                return;
            end
        
            % Obtener el elemento estructurante desde la UITable
            elementoUsuario = logical(app.UITable.Data); % convertir a lógico
            SE = strel('arbitrary', elementoUsuario);    % crear elemento estructurante

            % Mostrar el elemento estructurante en el UIAxes
            elementoUsuario = logical(app.UITable.Data);
            
            % Mostrar el ElementoEstructurante
            imshow(elementoUsuario, 'Parent', app.UIAxesElementoEstructurante);
            title(app.UIAxesElementoEstructurante, 'Elemento Estructurante');
            
            % Confirmación del usuario
            choice = questdlg('¿Desea aplicar la operación con este elemento?', ...
                'Confirmación', 'Sí', 'No', 'Sí');
            
            % Limpiar el UIAxes después de confirmar
            cla(app.UIAxesElementoEstructurante);
            
            % Cancelar si el usuario no confirma
            if strcmp(choice, 'No')
                return;
            end
        
            % Aplicar erosión
            imagenErosionada = imerode(app.ImagenProcesada > 0, SE); % convertir a binaria lógica

            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;           
        
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenErosionada) * 255;
        
            % Mostrar imagen original y erosionada
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_5);
        
            % Mostrar información
            infoProcesada = sprintf('Imagen erosionada: %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;

        end

        % Button pushed function: Apertura
        function AperturaButtonPushed(app, event)
            % Verificar si hay imagen binaria (procesada o original)
            if isempty(app.ImagenProcesada) || ~islogical(app.ImagenProcesada > 0)
                uialert(app.UIFigure, 'La imagen debe ser binaria antes de aplicar operaciones morfológicas.', 'Error');
                return;
            end
        
            % Obtener el elemento estructurante desde la UITable
            elementoUsuario = logical(app.UITable.Data); % convertir a lógico
            SE = strel('arbitrary', elementoUsuario);    % crear elemento estructurante

            % Mostrar el elemento estructurante en el UIAxes
            elementoUsuario = logical(app.UITable.Data);
            
            % Mostrar el ElementoEstructurante
            imshow(elementoUsuario, 'Parent', app.UIAxesElementoEstructurante);
            title(app.UIAxesElementoEstructurante, 'Elemento Estructurante');
            
            % Confirmación del usuario
            choice = questdlg('¿Desea aplicar la operación con este elemento?', ...
                'Confirmación', 'Sí', 'No', 'Sí');
            
            % Limpiar el UIAxes después de confirmar
            cla(app.UIAxesElementoEstructurante);
            
            % Cancelar si el usuario no confirma
            if strcmp(choice, 'No')
                return;
            end
        
            % Aplicar apertura (erosión + dilatación)
            imagenApertura = imopen(app.ImagenProcesada > 0, SE); % convertir a binaria lógica
        
            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;
            
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenApertura) * 255;
        
            % Mostrar imagen original y apertura
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_5);
        
            % Mostrar información
            infoProcesada = sprintf('Imagen apertura: %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;

        end

        % Button pushed function: Clausura
        function ClausuraButtonPushed(app, event)
            % Verificar si hay imagen binaria (procesada o original)
            if isempty(app.ImagenProcesada) || ~islogical(app.ImagenProcesada > 0)
                uialert(app.UIFigure, 'La imagen debe ser binaria antes de aplicar operaciones morfológicas.', 'Error');
                return;
            end
        
            % Obtener el elemento estructurante desde la UITable
            elementoUsuario = logical(app.UITable.Data); % convertir a lógico
            SE = strel('arbitrary', elementoUsuario);    % crear elemento estructurante

            % Mostrar el elemento estructurante en el UIAxes
            elementoUsuario = logical(app.UITable.Data);
            
            % Mostrar el ElementoEstructurante
            imshow(elementoUsuario, 'Parent', app.UIAxesElementoEstructurante);
            title(app.UIAxesElementoEstructurante, 'Elemento Estructurante');
            
            % Confirmación del usuario
            choice = questdlg('¿Desea aplicar la operación con este elemento?', ...
                'Confirmación', 'Sí', 'No', 'Sí');
            
            % Limpiar el UIAxes después de confirmar
            cla(app.UIAxesElementoEstructurante);
            
            % Cancelar si el usuario no confirma
            if strcmp(choice, 'No')
                return;
            end

        
            % Aplicar clausura (dilatación + erosión)
            imagenClausura = imclose(app.ImagenProcesada > 0, SE); % convertir a binaria lógica

            % Guardar en historial
            if isempty(app.HistorialProcesamiento5)
                app.HistorialProcesamiento5 = {};
            end
            app.HistorialProcesamiento5{end+1} = app.ImagenProcesada;
        
            % Actualizar imagen procesada
            app.ImagenProcesada = uint8(imagenClausura) * 255;
        
            % Mostrar imagen original y clausura
            imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_5);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_5);
        
            % Mostrar información
            infoProcesada = sprintf('Imagen clausura: %d x %d', ...
                size(app.ImagenProcesada, 1), size(app.ImagenProcesada, 2));
            app.LabelInfoProcesada_5.Text = infoProcesada;

        end

        % Button pushed function: CambiarOjos
        function CambiarColorOjosButtonPushed(app, event)
        % ----Reemplazo de tono en espacio HSV con máscara circular suave.---- 
        % Consiste en convertir la imagen a HSV, modificar el tono (Hue) y la saturación (S) únicamente en la región del iris,
        % usando una máscara circular con bordes difuminados para lograr una transición natural,
        % mientras se conserva el brillo original (canal V)
        % y se protege la pupila para mantener el realismo.

            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end

            % Guardar en historial
            if isempty(app.HistorialProcesamiento6)
                app.HistorialProcesamiento6 = {};
            end
            app.HistorialProcesamiento6{end+1} = app.ImagenProcesada;

            % --- 1) Selección de ojos (dos clics) ---
            fig = figure('Name', 'Selecciona los ojos', 'NumberTitle', 'off');
            imshow(app.ImagenOriginal);
            title('Haz clic en el centro del ojo derecho y luego en el izquierdo');
        
            hold on;
            puntos = zeros(2,2);
            for i = 1:2
                [x, y] = ginput(1);
                puntos(i,:) = [x, y];
                plot(x, y, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
                text(x+5, y, sprintf('Ojo %d', i), 'Color', 'yellow', 'FontSize', 12);
            end
            hold off;
            pause(0.6);
            close(fig);
        
            % --- 2) Parámetros y preparación ---
            imagenRGB_uint8 = app.ImagenOriginal;
            [alto, ancho, ~] = size(imagenRGB_uint8);
        
            % Tamaño de iris estimado en función del tamaño de imagen (puedes ajustar)
            R = round(0.06 * min(alto, ancho));         % radio del iris
            R = max(18, min(40, R));                    % límites razonables
            rp = round(0.28 * R);                       % radio de la pupila (protegida)
            feather = round(0.22 * R);                  % ancho del borde suave
        
            % Evitar que los centros queden muy pegados al borde
            coords = round(puntos);
            coords(:,1) = min(max(coords(:,1), R+1), ancho - R - 1);
            coords(:,2) = min(max(coords(:,2), R+1), alto - R - 1);
        
            % --- 3) Selección del color objetivo ---
            colores = {'Azul','Verde','Violeta','Marrón'};
            [sel, ok] = listdlg('PromptString','Selecciona el color:', ...
                                'SelectionMode','single','ListString',colores);
            if ~ok, return; end
        
            % Hue objetivo (en [0,1]) y saturación objetivo por color
            switch colores{sel}
                case 'Azul'
                    Ht = 0.58;  St = 0.75;
                case 'Verde'
                    Ht = 0.33;  St = 0.65;
                case 'Violeta'
                    Ht = 0.78;  St = 0.80;
                case 'Marrón'
                    % Marrón es un naranja oscuro; el brillo real del iris se preserva en V
                    Ht = 0.08;  St = 0.60;
            end
        
            % Intensidades de mezcla (puedes afinar si quieres más/menos cambio)
            alphaHue = 0.85;   % cuánto acercamos el tono al objetivo
            alphaSat = 0.55;   % cuánto ajustamos la saturación hacia St
        
            % --- 4) Conversión a HSV (única técnica: modificación de Hue/Sat) ---
            img = im2double(imagenRGB_uint8);
            hsvImg = rgb2hsv(img);
            H = hsvImg(:,:,1); S = hsvImg(:,:,2); V = hsvImg(:,:,3); %#ok<NASGU>
        
            % --- 5) Aplicación local por ojo con máscara circular suave ---
            for i = 1:2
                cx = coords(i,1);
                cy = coords(i,2);
        
                x1 = cx - R; x2 = cx + R;
                y1 = cy - R; y2 = cy + R;
        
                % Parches locales
                Hpatch = H(y1:y2, x1:x2);
                Spatch = S(y1:y2, x1:x2);
        
                [X, Y] = meshgrid(x1:x2, y1:y2);
                r = sqrt((X - cx).^2 + (Y - cy).^2);
        
                % Máscara w: 0 en pupila (r<=rp), 1 en el iris interior, borde difuminado
                w = zeros(size(r));
                % Región de iris "plena": entre rp y (R - feather)
                w(r > rp & r <= (R - feather)) = 1;
        
                % Borde con caída cosenoidal suave desde 1 -> 0
                edge = (r > (R - feather)) & (r <= R);
                if any(edge(:))
                    w(edge) = 0.5 * (1 + cos(pi * (r(edge) - (R - feather)) / feather));
                end
                % Fuera de R y dentro de rp, w = 0 (ya inicializado)
        
                % --- Reemplazo de Hue (interpolación circular) ---
                % Distancia angular mínima en el círculo del color
                dH = mod(Ht - Hpatch + 0.5, 1.0) - 0.5;
                Hpatch = Hpatch + alphaHue .* w .* dH;
                Hpatch = mod(Hpatch, 1.0);
        
                % --- Ajuste suave de saturación ---
                Spatch = Spatch + alphaSat .* w .* (St - Spatch);
                Spatch = max(0, min(1, Spatch));
        
                % Guardar de vuelta en imagen completa
                H(y1:y2, x1:x2) = Hpatch;
                S(y1:y2, x1:x2) = Spatch;
            end
        
            % --- 6) Reconstrucción y salida ---
            hsvImg(:,:,1) = H;
            hsvImg(:,:,2) = S;
            % hsvImg(:,:,3) = V;  % V ya mantiene luminancia original
            imagen_out = hsv2rgb(hsvImg);
            imagen_out = im2uint8(imagen_out);
        
            app.ImagenProcesada = imagen_out;
            imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_6);
            app.LabelInfoProcesada_6.Text = sprintf('Color de ojos cambiado a %s (HSV + máscara suave)', colores{sel});

        end

        % Button pushed function: DeshacerButton_6
        function DeshacerButtonPushed_6(app, event)
              if ~isempty(app.HistorialProcesamiento6)
                    % Recuperar el último estado
                    app.ImagenProcesada = app.HistorialProcesamiento6{end};
                    app.HistorialProcesamiento6(end) = [];
            
                    % Mostrar la imagen restaurada en el 4 tab
                    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_6);
            
            
                    % Mensaje informativo
                    app.LabelInfoProcesada_6.Text = 'Se ha deshecho la última acción.';
                else
                    uialert(app.UIFigure, 'No hay más acciones para deshacer.', 'Aviso');
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperacionesBsicasTab = uitab(app.TabGroup);
            app.OperacionesBsicasTab.Title = ' Operaciones Básicas';
            app.UIAxesOriginal = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesOriginal, 'Imagen Original')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcesada = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesProcesada, 'Imagen Procesada')
            app.UIAxesProcesada.XTick = [];
            app.UIAxesProcesada.YTick = [];
            app.TransformacionesgeomtricasPanel = uipanel(app.OperacionesBsicasTab);
            app.TransformacionesgeomtricasPanel.Title = ' Transformaciones geométricas';
            app.ModificarTamaoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.ModificarTamaoButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarTamanioButtonPushed, true);
            app.ModificarTamaoButton.Text = ' Modificar Tamaño';
            app.DesplazarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.DesplazarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @DesplazarImagenButtonPushed, true);
            app.DesplazarImagenButton.Text = 'Desplazar Imagen';
            app.RotarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.RotarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @RotarImagenButtonPushed, true);
            app.RotarImagenButton.Text = 'Rotar Imagen';
            app.EspejoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.EspejoButton.ButtonPushedFcn = createCallbackFcn(app, @EspejoImagenButtonPushed, true);
            app.EspejoButton.Text = 'Espejo';
            app.MosaicoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.MosaicoButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicoEspejoButtonPushed, true);
            app.MosaicoButton.Text = 'Mosaico';
            app.OperacionesdeintensidadPanel = uipanel(app.OperacionesBsicasTab);
            app.OperacionesdeintensidadPanel.Title = 'Operaciones de intensidad';
            app.InversinFotogrficaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinFotogrficaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirFotograficaButtonPushed, true);
            app.InversinFotogrficaButton.Text = 'Inversión Fotográfica';
            app.InversinBinariaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinBinariaButton.ButtonPushedFcn = createCallbackFcn(app, @vertirBinariaButtonPushed, true);
            app.InversinBinariaButton.Text = 'Inversión Binaria';
            app.AdicinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.AdicinButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionImagenButtonPushed, true);
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.SustraccinButton.ButtonPushedFcn = createCallbackFcn(app, @SustraccionImagenButtonPushed, true);
            app.SustraccinButton.Text = 'Sustracción';
            app.AnlisisdepxelesPanel = uipanel(app.OperacionesBsicasTab);
            app.AnlisisdepxelesPanel.Title = 'Análisis de píxeles';
            app.CalcularDistanciasButton = uibutton(app.AnlisisdepxelesPanel, 'push');
            app.CalcularDistanciasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDistanciasButtonPushed, true);
            app.CalcularDistanciasButton.Text = 'Calcular Distancias';
            app.LabelInfoOriginal = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoOriginal.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoProcesada.Text = 'LabelInfoProcesada';
            app.DeshacerButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer';
            app.EcualizacinTab = uitab(app.TabGroup);
            app.EcualizacinTab.Title = 'Ecualización';
            app.UIAxesOriginal_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesOriginal_2, 'Imagen Original')
            app.UIAxesOriginal_2.XTick = [];
            app.UIAxesOriginal_2.YTick = [];
            app.UIAxesProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesProcesada_2, 'Imagen Procesada')
            app.UIAxesProcesada_2.XTick = [];
            app.UIAxesProcesada_2.YTick = [];
            app.UIAxesHistograma = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistograma, 'Histograma')
            app.UIAxesHistograma.XTick = [];
            app.UIAxesHistograma.YTick = [];
            app.UIAxesHistogramaProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistogramaProcesada_2, 'Histograma')
            app.UIAxesHistogramaProcesada_2.XTick = [];
            app.UIAxesHistogramaProcesada_2.YTick = [];
            app.EcualizacinPanel = uipanel(app.EcualizacinTab);
            app.EcualizacinPanel.Title = 'Ecualización	';
            app.HistogramaButton = uibutton(app.EcualizacinPanel, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma	';
            app.StrecthButton = uibutton(app.EcualizacinPanel, 'push');
            app.StrecthButton.ButtonPushedFcn = createCallbackFcn(app, @StretchButtonPushed, true);
            app.StrecthButton.Text = 'Strecth';
            app.ShrinkButton = uibutton(app.EcualizacinPanel, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkButtonPushed, true);
            app.ShrinkButton.Text = 'Shrink';
            app.EcualizarButton = uibutton(app.EcualizacinPanel, 'push');
            app.EcualizarButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarImagenButtonPushed, true);
            app.EcualizarButton.Text = 'Ecualizar';
            app.DeshacerButton_2 = uibutton(app.EcualizacinTab, 'push');
            app.DeshacerButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_2, true);
            app.DeshacerButton_2.Text = 'Deshacer';
            app.LabelInfoOriginal_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoOriginal_2.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoProcesada_2.Text = 'LabelInfoProcesada';
            app.UmbralizacinTab = uitab(app.TabGroup);
            app.UmbralizacinTab.Title = 'Umbralización';
            app.UIAxesOriginal_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesOriginal_3, 'Imagen Original')
            app.UIAxesOriginal_3.XTick = [];
            app.UIAxesOriginal_3.YTick = [];
            app.UIAxesProcesada_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesProcesada_3, 'Imagen Procesada')
            app.UIAxesProcesada_3.XTick = [];
            app.UIAxesProcesada_3.YTick = [];
            app.UIAxesHistograma_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesHistograma_3, 'Histograma')
            app.UIAxesHistograma_3.XTick = [];
            app.UIAxesHistograma_3.YTick = [];
            app.UIAxesHistogramaProcesada_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesHistogramaProcesada_3, 'Histograma')
            app.UIAxesHistogramaProcesada_3.XTick = [];
            app.UIAxesHistogramaProcesada_3.YTick = [];
            app.UmbralizacinPanel = uipanel(app.UmbralizacinTab);
            app.UmbralizacinPanel.Title = 'Umbralización	';
            app.UmbralFijoButton = uibutton(app.UmbralizacinPanel, 'push');
            app.UmbralFijoButton.ButtonPushedFcn = createCallbackFcn(app, @UmbralFijoButtonPushe, true);
            app.UmbralFijoButton.Text = 'Umbral Fijo';
            app.UmbraladaptativoButton = uibutton(app.UmbralizacinPanel, 'push');
            app.UmbraladaptativoButton.ButtonPushedFcn = createCallbackFcn(app, @UmbralAdaptativoButtonPushed, true);
            app.UmbraladaptativoButton.Text = 'Umbral adaptativo';
            app.DeshacerButton_3 = uibutton(app.UmbralizacinTab, 'push');
            app.DeshacerButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_3, true);
            app.DeshacerButton_3.Text = 'Deshacer';
            app.LabelInfoOriginal_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoOriginal_3.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoProcesada_3.Text = 'LabelInfoProcesada';
            app.FiltrosTab = uitab(app.TabGroup);
            app.FiltrosTab.Title = 'Filtros ';
            app.UIAxesProcesada_4 = uiaxes(app.FiltrosTab);
            title(app.UIAxesProcesada_4, 'Imagen Procesada')
            app.UIAxesProcesada_4.XTick = [];
            app.UIAxesProcesada_4.YTick = [];
            app.UIAxesRuido = uiaxes(app.FiltrosTab);
            title(app.UIAxesRuido, 'Imagen Ruido')
            app.UIAxesRuido.XTick = [];
            app.UIAxesRuido.YTick = [];
            app.UIAxesOriginal_4 = uiaxes(app.FiltrosTab);
            title(app.UIAxesOriginal_4, 'Imagen Original')
            app.UIAxesOriginal_4.XTick = [];
            app.UIAxesOriginal_4.YTick = [];
            app.ReducirRuidoPanel = uipanel(app.FiltrosTab);
            app.ReducirRuidoPanel.Title = 'Reducir Ruido';
            app.MedianaButton = uibutton(app.ReducirRuidoPanel, 'push');
            app.MedianaButton.ButtonPushedFcn = createCallbackFcn(app, @MedianaButtonPushed, true);
            app.MedianaButton.Text = 'Mediana';
            app.MediaButton = uibutton(app.ReducirRuidoPanel, 'push');
            app.MediaButton.ButtonPushedFcn = createCallbackFcn(app, @MediaButtonPushed, true);
            app.MediaButton.Text = 'Media';
            app.ModaButton = uibutton(app.ReducirRuidoPanel, 'push');
            app.ModaButton.ButtonPushedFcn = createCallbackFcn(app, @ModaButtonPushed, true);
            app.ModaButton.Text = 'Moda';
            app.GausianoButton = uibutton(app.ReducirRuidoPanel, 'push');
            app.GausianoButton.ButtonPushedFcn = createCallbackFcn(app, @GausianoButtonPushed, true);
            app.GausianoButton.Text = 'Gausiano';
            app.OrdennButton = uibutton(app.ReducirRuidoPanel, 'push');
            app.OrdennButton.ButtonPushedFcn = createCallbackFcn(app, @OrdennButtonPushed, true);
            app.OrdennButton.Text = 'Orden n';
            app.AgregarRuidoPanel = uipanel(app.FiltrosTab);
            app.AgregarRuidoPanel.Title = 'Agregar Ruido';
            app.RuidoGaussianoButton = uibutton(app.AgregarRuidoPanel, 'push');
            app.RuidoGaussianoButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoGaussianoButtonPushed, true);
            app.RuidoGaussianoButton.Text = 'Ruido Gaussiano';
            app.SalyPimientaButton = uibutton(app.AgregarRuidoPanel, 'push');
            app.SalyPimientaButton.ButtonPushedFcn = createCallbackFcn(app, @SalyPimientaButtonPushed, true);
            app.SalyPimientaButton.Text = 'Sal y Pimienta';
            app.RuidoPoissonButton = uibutton(app.AgregarRuidoPanel, 'push');
            app.RuidoPoissonButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoPoissonButtonPushed, true);
            app.RuidoPoissonButton.Text = 'Ruido Poisson';
            app.ResaltarcaractersticasPanel = uipanel(app.FiltrosTab);
            app.ResaltarcaractersticasPanel.Title = ' Resaltar características';
            app.Laplaciano8vecinosButton = uibutton(app.ResaltarcaractersticasPanel, 'push');
            app.Laplaciano8vecinosButton.ButtonPushedFcn = createCallbackFcn(app, @Laplaciano8vecinosButtonPushed, true);
            app.Laplaciano8vecinosButton.Text = 'Laplaciano 8-vecinos';
            app.Laplaciano4vecinosButton = uibutton(app.ResaltarcaractersticasPanel, 'push');
            app.Laplaciano4vecinosButton.ButtonPushedFcn = createCallbackFcn(app, @Laplaciano4vecinosButtonPushed, true);
            app.Laplaciano4vecinosButton.Text = 'Laplaciano 4-vecinos';
            app.DeshacerButton_4 = uibutton(app.FiltrosTab, 'push');
            app.DeshacerButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_4, true);
            app.DeshacerButton_4.Text = 'Deshacer';
            app.LabelInfoOriginal_4 = uilabel(app.FiltrosTab);
            app.LabelInfoOriginal_4.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_4 = uilabel(app.FiltrosTab);
            app.LabelInfoProcesada_4.Text = 'LabelInfoProcesada';
            app.DetecciondebordesPanel = uipanel(app.FiltrosTab);
            app.DetecciondebordesPanel.Title = 'Deteccion de bordes';
            app.SobelXButton = uibutton(app.DetecciondebordesPanel, 'push');
            app.SobelXButton.ButtonPushedFcn = createCallbackFcn(app, @SobelXButtonPushed, true);
            app.SobelXButton.Text = 'Sobel	X';
            app.SobelYButton = uibutton(app.DetecciondebordesPanel, 'push');
            app.SobelYButton.ButtonPushedFcn = createCallbackFcn(app, @SobelYButtonPushed, true);
            app.SobelYButton.Text = 'Sobel	Y';
            app.SobelXYButton = uibutton(app.DetecciondebordesPanel, 'push');
            app.SobelXYButton.ButtonPushedFcn = createCallbackFcn(app, @SobelXYButtonPushed, true);
            app.SobelXYButton.Text = 'Sobel	X + Y';
            app.PrewittButton = uibutton(app.DetecciondebordesPanel, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.MorfolgicasTab = uitab(app.TabGroup);
            app.MorfolgicasTab.Title = 'Morfológicas';
            app.UIAxesProcesada_5 = uiaxes(app.MorfolgicasTab);
            title(app.UIAxesProcesada_5, 'Imagen Procesada')
            app.UIAxesProcesada_5.XTick = [];
            app.UIAxesProcesada_5.YTick = [];
            app.UIAxesOriginal_5 = uiaxes(app.MorfolgicasTab);
            title(app.UIAxesOriginal_5, 'Imagen Original')
            app.UIAxesOriginal_5.XTick = [];
            app.UIAxesOriginal_5.YTick = [];
            app.UIAxesElementoEstructurante = uiaxes(app.MorfolgicasTab);
            title(app.UIAxesElementoEstructurante, 'Elemento Estructurante')
            app.UIAxesElementoEstructurante.XTick = [];
            app.UIAxesElementoEstructurante.YTick = [];
            app.BinarizarlaImagenPanel = uipanel(app.MorfolgicasTab);
            app.BinarizarlaImagenPanel.Title = 'Binarizar la Imagen';
            app.metodoadaptativoButton = uibutton(app.BinarizarlaImagenPanel, 'push');
            app.metodoadaptativoButton.ButtonPushedFcn = createCallbackFcn(app, @metodoadaptativoButtonPushed, true);
            app.metodoadaptativoButton.Text = 'metodo adaptativo';
            app.UmbralDadoButton = uibutton(app.BinarizarlaImagenPanel, 'push');
            app.UmbralDadoButton.ButtonPushedFcn = createCallbackFcn(app, @UmbralDadoButtonPushed, true);
            app.UmbralDadoButton.Text = 'Ingresar un Umbral';
            app.DeshacerButton_5 = uibutton(app.MorfolgicasTab, 'push');
            app.DeshacerButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_5, true);
            app.DeshacerButton_5.Text = 'Deshacer';
            app.LabelInfoOriginal_5 = uilabel(app.MorfolgicasTab);
            app.LabelInfoOriginal_5.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_5 = uilabel(app.MorfolgicasTab);
            app.LabelInfoProcesada_5.Text = 'LabelInfoProcesada';
            app.elementoestructurantePanel = uipanel(app.MorfolgicasTab);
            app.elementoestructurantePanel.Title = 'elemento estructurante';
            app.UITable = uitable(app.elementoestructurantePanel);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Dilatacion = uibutton(app.elementoestructurantePanel, 'push');
            app.Dilatacion.ButtonPushedFcn = createCallbackFcn(app, @DilatacionButtonPushed, true);
            app.Dilatacion.Text = 'Dilatación';
            app.Erosion = uibutton(app.elementoestructurantePanel, 'push');
            app.Erosion.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.Erosion.Text = 'Erosión';
            app.Apertura = uibutton(app.elementoestructurantePanel, 'push');
            app.Apertura.ButtonPushedFcn = createCallbackFcn(app, @AperturaButtonPushed, true);
            app.Apertura.Text = 'Apertura';
            app.Clausura = uibutton(app.elementoestructurantePanel, 'push');
            app.Clausura.ButtonPushedFcn = createCallbackFcn(app, @ClausuraButtonPushed, true);
            app.Clausura.Text = 'Clausura	';
            app.Instruccionessecargaralamatrixycambielosvalorespor0sy1sLabel = uilabel(app.elementoestructurantePanel);
            app.Instruccionessecargaralamatrixycambielosvalorespor0sy1sLabel.Text = {'Instrucciones: se cargara la matrix y'; 'cambie los valores por 0s y 1s.'};
            app.ColordeOjosTab = uitab(app.TabGroup);
            app.ColordeOjosTab.Title = 'Color de Ojos';
            app.UIAxesProcesada_6 = uiaxes(app.ColordeOjosTab);
            title(app.UIAxesProcesada_6, 'Imagen Procesada')
            app.UIAxesProcesada_6.XTick = [];
            app.UIAxesProcesada_6.YTick = [];
            app.UIAxesOriginal_6 = uiaxes(app.ColordeOjosTab);
            title(app.UIAxesOriginal_6, 'Imagen Original')
            app.UIAxesOriginal_6.XTick = [];
            app.UIAxesOriginal_6.YTick = [];
            app.LabelInfoOriginal_6 = uilabel(app.ColordeOjosTab);
            app.LabelInfoOriginal_6.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_6 = uilabel(app.ColordeOjosTab);
            app.LabelInfoProcesada_6.Text = 'LabelInfoProcesada';
            app.CambiarcolodeojosPanel = uipanel(app.ColordeOjosTab);
            app.CambiarcolodeojosPanel.Title = 'Cambiar colo de ojos';
            app.CambiarOjos = uibutton(app.CambiarcolodeojosPanel, 'push');
            app.CambiarOjos.ButtonPushedFcn = createCallbackFcn(app, @CambiarColorOjosButtonPushed, true);
            app.CambiarOjos.Text = 'Cambiar Color HSV';
            app.DeshacerButton_6 = uibutton(app.ColordeOjosTab, 'push');
            app.DeshacerButton_6.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed_6, true);
            app.DeshacerButton_6.Text = 'Deshacer';
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.PROCESADORDEIMAGENESLabel = uilabel(app.UIFigure);
            app.PROCESADORDEIMAGENESLabel.Text = 'PROCESADOR DE IMAGENES';
            app.GuardarImagenButton = uibutton(app.UIFigure, 'push');
            app.GuardarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButtonPushed, true);
            app.GuardarImagenButton.Text = 'Guardar Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1___7Equipo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end